classdef dataRecordApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        dataRecordUIFigure         matlab.ui.Figure
        ch1Panel                   matlab.ui.container.Panel
        ch1Axes                    matlab.ui.control.UIAxes
        ch2Panel                   matlab.ui.container.Panel
        ch2Axes                    matlab.ui.control.UIAxes
        ch3Panel                   matlab.ui.container.Panel
        ch3Axes                    matlab.ui.control.UIAxes
        ch4Panel                   matlab.ui.container.Panel
        ch4Axes                    matlab.ui.control.UIAxes
        DevicePanel                matlab.ui.container.Panel
        SelectdeviceDropDownLabel  matlab.ui.control.Label
        devicesDropDown            matlab.ui.control.DropDown
        ConnectButton              matlab.ui.control.Button
        connectionLabel            matlab.ui.control.Label
        SendtoliveButton           matlab.ui.control.Button
        liveLabel                  matlab.ui.control.Label
        RecordPanel                matlab.ui.container.Panel
        MovementDropDownLabel      matlab.ui.control.Label
        MovementDropDown           matlab.ui.control.DropDown
        SubjectEditFieldLabel      matlab.ui.control.Label
        SubjectEditField           matlab.ui.control.EditField
        recordButton               matlab.ui.control.Button
    end


    properties (Access = public)
        bitalinoObj; % Description
        ch1Data;
        ch2Data;
        ch3Data;
        ch4Data; % Description
        availableDevices;
        connection;
        isStreaming;
        isRecording;
        timerObj;
    end

    methods (Access = private)
    
        function timerFcn_callback(app)
            rawData = read(app.bitalinoObj);
            % plot live data
            
            
            % save data to 
        end
        
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.availableDevices=Bitalino.findDevices();
            app.devicesDropDown.Items = app.availableDevices';
            app.isStreaming = 0;
            app.isRecording = 0;
            app.bitalinoObj = [];
            app.connection = 0;
            app.timerObj = timer('TimerFcn',@(~,~) timerFcn_callback,'Period',0.1, ...
                 'ExecutionMode','FixedRate','BusyMode','drop');
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if(app.connection == 0)
                try
                    app.bitalinoObj = Bitalino(app.devicesDropDown.Value);
                    app.connection = 1;
                catch
                    error('Connection failed');
                end
            end
        end

        % Button pushed function: SendtoliveButton
        function SendtoliveButtonPushed(app, event)
            if(app.connection == 1)
               if(app.isStreaming == 0)
                  startBackground(app.bitalinoObj);
                  start(app.timerObj);
                  app.isStreaming = 1;
               end
            end
        end

        % Button pushed function: recordButton
        function recordButtonPushed(app, event)
            if(app.isRecording == 0)
                app.isRecording = 1;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.dataRecordUIFigure = uifigure;
            app.dataRecordUIFigure.Name = 'Data record';
            setAutoResize(app, app.dataRecordUIFigure, true)
            app.ch1Panel = uipanel(app.dataRecordUIFigure);
            app.ch1Panel.Title = 'Channel1';
            app.ch1Axes = uiaxes(app.ch1Panel);
            app.ch2Panel = uipanel(app.dataRecordUIFigure);
            app.ch2Panel.Title = 'Channel2';
            app.ch2Axes = uiaxes(app.ch2Panel);
            app.ch3Panel = uipanel(app.dataRecordUIFigure);
            app.ch3Panel.Title = 'Channel3';
            app.ch3Axes = uiaxes(app.ch3Panel);
            app.ch4Panel = uipanel(app.dataRecordUIFigure);
            app.ch4Panel.Title = 'Channel4';
            app.ch4Axes = uiaxes(app.ch4Panel);
            app.DevicePanel = uipanel(app.dataRecordUIFigure);
            app.DevicePanel.Title = 'Device';
            app.SelectdeviceDropDownLabel = uilabel(app.DevicePanel);
            app.SelectdeviceDropDownLabel.Text = 'Select device';
            app.devicesDropDown = uidropdown(app.DevicePanel);
            app.ConnectButton = uibutton(app.DevicePanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.connectionLabel = uilabel(app.DevicePanel);
            app.connectionLabel.Text = 'connection status';
            app.SendtoliveButton = uibutton(app.DevicePanel, 'push');
            app.SendtoliveButton.ButtonPushedFcn = createCallbackFcn(app, @SendtoliveButtonPushed, true);
            app.SendtoliveButton.Text = 'Send to live';
            app.liveLabel = uilabel(app.DevicePanel);
            app.RecordPanel = uipanel(app.dataRecordUIFigure);
            app.RecordPanel.Title = 'Record';
            app.MovementDropDownLabel = uilabel(app.RecordPanel);
            app.MovementDropDownLabel.Text = 'Movement';
            app.MovementDropDown = uidropdown(app.RecordPanel);
            app.MovementDropDown.Items = {'fist', 'point', 'wrist_flexion', 'wrist_extension', 'wrist_pronation', 'wrist_supination'};
            app.MovementDropDown.Value = 'fist';
            app.SubjectEditFieldLabel = uilabel(app.RecordPanel);
            app.SubjectEditFieldLabel.Text = 'Subject';
            app.SubjectEditField = uieditfield(app.RecordPanel, 'text');
            app.recordButton = uibutton(app.RecordPanel, 'push');
            app.recordButton.ButtonPushedFcn = createCallbackFcn(app, @recordButtonPushed, true);
            app.recordButton.Text = 'Start';
        end
    end

    methods (Access = public)

        % Construct app
        function app = dataRecordApp()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.dataRecordUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.dataRecordUIFigure)
        end
    end
end