classdef dataRecordApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        dataRecordUIFigure         matlab.ui.Figure
        ch1Panel                   matlab.ui.container.Panel
        ch1Axes                    matlab.ui.control.UIAxes
        CheckBox1                  matlab.ui.control.CheckBox
        ch2Panel                   matlab.ui.container.Panel
        ch2Axes                    matlab.ui.control.UIAxes
        CheckBox2                  matlab.ui.control.CheckBox
        ch3Panel                   matlab.ui.container.Panel
        ch3Axes                    matlab.ui.control.UIAxes
        CheckBox3                  matlab.ui.control.CheckBox
        ch4Panel                   matlab.ui.container.Panel
        ch4Axes                    matlab.ui.control.UIAxes
        CheckBox4                  matlab.ui.control.CheckBox
        DevicePanel                matlab.ui.container.Panel
        ConnectButton              matlab.ui.control.Button
        connectionLabel            matlab.ui.control.Label
        LiveonButton               matlab.ui.control.Button
        liveLabel                  matlab.ui.control.Label
        MacEditFieldLabel          matlab.ui.control.Label
        MacEditField               matlab.ui.control.EditField
        SamplingrateDropDownLabel  matlab.ui.control.Label
        SamplingrateDropDown       matlab.ui.control.DropDown
        LiveoffButton              matlab.ui.control.Button
        DisconnectButton           matlab.ui.control.Button
        RecordPanel                matlab.ui.container.Panel
        MovementDropDownLabel      matlab.ui.control.Label
        MovementDropDown           matlab.ui.control.DropDown
        SubjectEditFieldLabel      matlab.ui.control.Label
        SubjectEditField           matlab.ui.control.EditField
        recordButton               matlab.ui.control.Button
        recordEndButton            matlab.ui.control.Button
        triallabelEditFieldLabel   matlab.ui.control.Label
        trialEditField             matlab.ui.control.EditField
        counterLabel               matlab.ui.control.Label
    end


    properties (Access = public)
        bitalinoObj; % Description
        ch1Data;
        ch2Data;
        ch3Data;
        ch4Data; % Description
        availableDevices;
        connection;
        isStreaming;
        isRecording;
        timerObj;
        timerObj_beep;
        counter=0;
        ch1Plot;
        ch2Plot;
        ch3Plot;
        ch4Plot; % Description
        nSample = 100;
        nDatarecord = 0;
        beepIndex = [];
    end

    methods (Access = private)
    
        function timerFcn_callback(app)
            
            rawData = app.bitalinoObj.read(app.nSample);
            nData = size(rawData,1);
            if nData == 0
                disp('no data read. wait, restart or check connection');
            end
            %rawData_analog = rawData(:,6:end);
            %rawData_analog(rawData_analog == -1) = 511.5;
            % convert adc data to emg data (mV)
            %emgData = adc2emg(rawData_analog);
            ch1Data_new = adc2emg(rawData(:,6));
            ch2Data_new = adc2emg(rawData(:,7));
            ch3Data_new = adc2emg(rawData(:,8));
            ch4Data_new = adc2emg(rawData(:,9));
            
            if(app.isRecording == 1)
                app.ch1Data = [app.ch1Data; ch1Data_new];
                app.ch2Data = [app.ch2Data; ch2Data_new];
                app.ch3Data = [app.ch3Data; ch3Data_new];
                app.ch4Data = [app.ch4Data; ch4Data_new];
                app.nDatarecord = app.nDatarecord + nData;
            end
            
            
            ch1Data_toplot = [get(app.ch1Plot,'YData') ch1Data_new'];
            ch2Data_toplot = [get(app.ch2Plot,'YData') ch2Data_new'];
            ch3Data_toplot = [get(app.ch3Plot,'YData') ch3Data_new'];
            ch4Data_toplot = [get(app.ch4Plot,'YData') ch4Data_new'];
            
            ntoshow = 3000;
            if length(ch1Data_toplot) > ntoshow
                ch1Data_toplot = ch1Data_toplot(end-ntoshow+1:end);
                ch2Data_toplot = ch2Data_toplot(end-ntoshow+1:end);
                ch3Data_toplot = ch3Data_toplot(end-ntoshow+1:end);
                ch4Data_toplot = ch4Data_toplot(end-ntoshow+1:end);
            end
            set(app.ch1Plot,'XData',1:length(ch1Data_toplot),'YData',ch1Data_toplot);
            set(app.ch2Plot,'XData',1:length(ch2Data_toplot),'YData',ch2Data_toplot);
            set(app.ch3Plot,'XData',1:length(ch3Data_toplot),'YData',ch3Data_toplot);
            set(app.ch4Plot,'XData',1:length(ch4Data_toplot),'YData',ch4Data_toplot);
            refreshdata;
            drawnow;
            
           
        end
        
        
        function timerBeep(app)
            %beep
            app.counter = app.counter +1;
            app.counterLabel.Text = num2str(app.counter);
            app.beepIndex = [app.beepIndex;app.nDatarecord];
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.availableDevices=Bitalino.findDevices();
            %app.devicesDropDown.Items = app.availableDevices';
            app.isStreaming = 0;
            app.isRecording = 0;
            app.bitalinoObj = bitalino();
            app.connection = 0;
            app.ch1Plot = plot(app.ch1Axes,0);
            set(app.ch1Axes,'XTickLabel',[]);
            axis tight; grid on;
            app.ch2Plot = plot(app.ch2Axes,0);
            set(app.ch2Axes,'XTickLabel',[]);
            axis tight; grid on;
            app.ch3Plot = plot(app.ch3Axes,0);
            set(app.ch3Axes,'XTickLabel',[]);
            axis tight; grid on;
            app.ch4Plot = plot(app.ch4Axes,0);
            set(app.ch4Axes,'XTickLabel',[]);
            axis tight; grid on;
            app.timerObj = timer('TimerFcn',@(~,~) timerFcn_callback(app),'Period',0.1, ...
                 'ExecutionMode','FixedRate','BusyMode','drop');
            app.timerObj_beep = timer('TimerFcn',@(~,~) timerBeep(app),'Period',4, ...
                 'ExecutionMode','FixedRate','BusyMode','drop'); 
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
           
                try
                    app.bitalinoObj = app.bitalinoObj.open(app.MacEditField.Value,str2num(app.SamplingrateDropDown.Value));
                    if (app.bitalinoObj.connection == 1)
                    app.connection = 1;
                    
                    app.connectionLabel.Text = 'Connected';
                    end
                    %disp('Connection established');
                catch
                    error('Connection failed');
                end


        end

        % Button pushed function: LiveonButton
        function LiveonButtonPushed(app, event)
           
                   if (app.CheckBox1.Value == true)
                       c1 = 0;
                   else
                       c1 = [];
                   end
                   if (app.CheckBox2.Value == true)
                       c2 = 1;
                   else
                       c2 = [];
                   end
                   if (app.CheckBox3.Value == true)
                       c3 = 2;
                   else
                       c3 = [];
                   end
                   if (app.CheckBox4.Value == true)
                       c4 = 3;
                   else
                       c4 = [];
                   end
                   analogChannels = [0, 1, 2, 3];
                   app.bitalinoObj = start(app.bitalinoObj,analogChannels);
                   app.isStreaming = 1;
                   start(app.timerObj);
                   app.liveLabel.Text = 'on';
           
        end

        % Button pushed function: recordButton
        function recordButtonPushed(app, event)
            if(app.isRecording == 0)
                % before staring, empty the data record variables
                app.ch1Data = [];
                app.ch2Data = [];
                app.ch3Data = [];
                app.ch4Data = [];
                app.isRecording = 1;
                app.nDatarecord = 0;
                app.beepIndex = [];
                start(app.timerObj_beep);
            end
        end

        % Button pushed function: recordEndButton
        function recordEndButtonPushed(app, event)
            if(app.isRecording == 1)
            % save data to 
            filepathtowrite = ['data/' app.SubjectEditField.Value '/record/' app.MovementDropDown.Value '_' app.trialEditField.Value '.csv'];
            app.ch1Data(app.ch1Data == -1 ) = 511.5;
            app.ch2Data(app.ch2Data == -1 ) = 511.5;
            app.ch3Data(app.ch3Data == -1 ) = 511.5;
            app.ch4Data(app.ch4Data == -1 ) = 511.5;
            writetable(table(app.ch1Data,app.ch2Data,app.ch3Data,app.ch4Data),filepathtowrite);
            % empty the data after saving
            app.ch1Data = [];
            app.ch2Data = [];
            app.ch3Data = [];
            app.ch4Data = [];
            app.isRecording = 0;
            stop(app.timerObj_beep);
            app.counter = 0;
            end
        end

        % Close request function: dataRecordUIFigure
        function dataRecordUIFigureCloseRequest(app, event)
            
            stop(app.timerObj);
            
            %app.bitalinoObj = app.bitalinoObj.stop();
            app.bitalinoObj = app.bitalinoObj.close();
            delete(app.timerObj);
            %delete(app.bitalinoObj);
            
            delete(app);
            
        end

        % Button pushed function: LiveoffButton
        function LiveoffButtonPushed(app, event)
            stop(app.timerObj);
            app.liveLabel.Text = 'off';
            app.bitalinoObj.stop();
            app.isStreaming = 0;
                   
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.bitalinoObj = app.bitalinoObj.close();
            app.connection = 0;
            
            app.connectionLabel.Text = 'Disconnected';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.dataRecordUIFigure = uifigure;
            app.dataRecordUIFigure.Name = 'Data record';
            app.dataRecordUIFigure.CloseRequestFcn = createCallbackFcn(app, @dataRecordUIFigureCloseRequest, true);
            setAutoResize(app, app.dataRecordUIFigure, true)
            app.ch1Panel = uipanel(app.dataRecordUIFigure);
            app.ch1Panel.Title = 'Channel1';
            app.ch1Axes = uiaxes(app.ch1Panel);
            app.CheckBox1 = uicheckbox(app.ch1Panel);
            app.CheckBox1.Text = '';
            app.CheckBox1.Value = true;
            app.ch2Panel = uipanel(app.dataRecordUIFigure);
            app.ch2Panel.Title = 'Channel2';
            app.ch2Axes = uiaxes(app.ch2Panel);
            app.CheckBox2 = uicheckbox(app.ch2Panel);
            app.CheckBox2.Text = '';
            app.CheckBox2.Value = true;
            app.ch3Panel = uipanel(app.dataRecordUIFigure);
            app.ch3Panel.Title = 'Channel3';
            app.ch3Axes = uiaxes(app.ch3Panel);
            app.CheckBox3 = uicheckbox(app.ch3Panel);
            app.CheckBox3.Text = '';
            app.CheckBox3.Value = true;
            app.ch4Panel = uipanel(app.dataRecordUIFigure);
            app.ch4Panel.Title = 'Channel4';
            app.ch4Axes = uiaxes(app.ch4Panel);
            app.CheckBox4 = uicheckbox(app.ch4Panel);
            app.CheckBox4.Text = '';
            app.CheckBox4.Value = true;
            app.DevicePanel = uipanel(app.dataRecordUIFigure);
            app.DevicePanel.Title = 'Device';
            app.ConnectButton = uibutton(app.DevicePanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.connectionLabel = uilabel(app.DevicePanel);
            app.connectionLabel.Text = 'disconnected';
            app.LiveonButton = uibutton(app.DevicePanel, 'push');
            app.LiveonButton.ButtonPushedFcn = createCallbackFcn(app, @LiveonButtonPushed, true);
            app.LiveonButton.Text = 'Live on';
            app.liveLabel = uilabel(app.DevicePanel);
            app.liveLabel.Text = 'off';
            app.MacEditFieldLabel = uilabel(app.DevicePanel);
            app.MacEditFieldLabel.Text = 'Mac';
            app.MacEditField = uieditfield(app.DevicePanel, 'text');
            app.MacEditField.Value = '201607181511';
            app.SamplingrateDropDownLabel = uilabel(app.DevicePanel);
            app.SamplingrateDropDownLabel.Text = 'Sampling rate';
            app.SamplingrateDropDown = uidropdown(app.DevicePanel);
            app.SamplingrateDropDown.Items = {'1000', '100', '10'};
            app.SamplingrateDropDown.ItemsData = {'1000', '100', '10'};
            app.SamplingrateDropDown.Value = '1000';
            app.LiveoffButton = uibutton(app.DevicePanel, 'push');
            app.LiveoffButton.ButtonPushedFcn = createCallbackFcn(app, @LiveoffButtonPushed, true);
            app.LiveoffButton.Text = 'Live off';
            app.DisconnectButton = uibutton(app.DevicePanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.RecordPanel = uipanel(app.dataRecordUIFigure);
            app.RecordPanel.Title = 'Record';
            app.MovementDropDownLabel = uilabel(app.RecordPanel);
            app.MovementDropDownLabel.Text = 'Movement';
            app.MovementDropDown = uidropdown(app.RecordPanel);
            app.MovementDropDown.Items = {'relax', 'fist', 'point', 'wrist_flexion', 'wrist_extension', 'wrist_pronation', 'wrist_supination'};
            app.MovementDropDown.Value = 'relax';
            app.SubjectEditFieldLabel = uilabel(app.RecordPanel);
            app.SubjectEditFieldLabel.Text = 'Subject';
            app.SubjectEditField = uieditfield(app.RecordPanel, 'text');
            app.recordButton = uibutton(app.RecordPanel, 'push');
            app.recordButton.ButtonPushedFcn = createCallbackFcn(app, @recordButtonPushed, true);
            app.recordButton.Text = 'Start';
            app.recordEndButton = uibutton(app.RecordPanel, 'push');
            app.recordEndButton.ButtonPushedFcn = createCallbackFcn(app, @recordEndButtonPushed, true);
            app.recordEndButton.Text = 'End';
            app.triallabelEditFieldLabel = uilabel(app.RecordPanel);
            app.triallabelEditFieldLabel.Text = 'trial label';
            app.trialEditField = uieditfield(app.RecordPanel, 'text');
            app.counterLabel = uilabel(app.RecordPanel);
            app.counterLabel.Text = '0';
        end
    end

    methods (Access = public)

        % Construct app
        function app = dataRecordApp()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.dataRecordUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.dataRecordUIFigure)
        end
    end
end